classdef ControlUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        Menu_2                       matlab.ui.container.Menu
        SettingsMenu_2               matlab.ui.container.Menu
        RecalibrateWaveplateMenu     matlab.ui.container.Menu
        StageAdjustmentPanel         matlab.ui.container.Panel
        LeftButton                   matlab.ui.control.Button
        RightButton                  matlab.ui.control.Button
        DownButton                   matlab.ui.control.Button
        UpButton                     matlab.ui.control.Button
        StepLengthumEditFieldLabel   matlab.ui.control.Label
        StepLengthumEditField        matlab.ui.control.NumericEditField
        Button_3                     matlab.ui.control.Button
        Button_4                     matlab.ui.control.Button
        EditField                    matlab.ui.control.EditField
        XPosEditFieldLabel           matlab.ui.control.Label
        XPosEditField                matlab.ui.control.NumericEditField
        YPosEditFieldLabel           matlab.ui.control.Label
        YPosEditField                matlab.ui.control.NumericEditField
        PowerAdjustmentPanel         matlab.ui.container.Panel
        EstPowerGaugeLabel           matlab.ui.control.Label
        EstPowerGauge                matlab.ui.control.LinearGauge
        JEditFieldLabel              matlab.ui.control.Label
        JEditField                   matlab.ui.control.NumericEditField
        MovetoPowerJEditFieldLabel   matlab.ui.control.Label
        MovetoPowerJEditField        matlab.ui.control.NumericEditField
        MoveByStepdegEditFieldLabel  matlab.ui.control.Label
        MoveByStepdegEditField       matlab.ui.control.NumericEditField
        Button                       matlab.ui.control.Button
        Button_2                     matlab.ui.control.Button
        MovetoAngleEditFieldLabel    matlab.ui.control.Label
        MovetoAngleEditField         matlab.ui.control.NumericEditField
        CurrentPosdegEditFieldLabel  matlab.ui.control.Label
        CurrentPosdegEditField       matlab.ui.control.NumericEditField
        SettingsDebuggingPanel       matlab.ui.container.Panel
        XDeviceEditFieldLabel        matlab.ui.control.Label
        XDeviceEditField             matlab.ui.control.NumericEditField
        YDeviceEditFieldLabel        matlab.ui.control.Label
        YDeviceEditField             matlab.ui.control.NumericEditField
        ZDeviceEditFieldLabel        matlab.ui.control.Label
        ZDeviceEditField             matlab.ui.control.NumericEditField
        XYMotorLampLabel             matlab.ui.control.Label
        XYMotorLamp                  matlab.ui.control.Lamp
        WaveplateLampLabel           matlab.ui.control.Label
        WaveplateLamp                matlab.ui.control.Lamp
        XYHomeButton                 matlab.ui.control.Button
        ThetaHomeButton              matlab.ui.control.Button
        ShutterControlPanel          matlab.ui.container.Panel
        ShutterStatusKnobLabel       matlab.ui.control.Label
        ShutterStatusKnob            matlab.ui.control.DiscreteKnob
        XYMotorLamp_2Label           matlab.ui.control.Label
        XYMotorLamp_2                matlab.ui.control.Lamp
        FireSinglePulseButton        matlab.ui.control.StateButton
    end


    properties (Access = private)
        PortHandle % Description
        XMotor % Device control handle for XMotor
        YMotor % Device control handle for YMotor
        ZMotor
        COM_Port = 'COM4' % COM Port used
        ThetaMotor %Waveplate motor
        StepConv = 4.9E-1 % 4.9E-1 um/ustep
        ThetaConv = 0.005625; %degrees/ustep;
        SamplerRatio = 0.01 % a 1.0% beam sampler
        Arduino % arduino uno powering shutter
    end

    properties (Access = public)
        CalPower % Calibration table for waveplate angle-pulse power dependence
    end

    methods (Access = private)
    
        function results = MoveByStep(app,int,varargin)
            %decide which direction to move based on int given
            %Assign device instance accordingly
            switch int
                case 0
                    Device = app.XMotor;
                    dir = 1;
                case 1
                    Device = app.XMotor;
                    dir = -1;
                case 2
                    Device = app.YMotor;
                    dir = -1;
                case 3
                    Device = app.YMotor;
                    dir = 1;
                case 4
                    Device = app.ZMotor;
                    dir = 1;
                case 5
                    Device = app.ZMotor;
                    dir = -1;
            end
            %if moving by step from left-right button, will take GUI input
            %for distance (in meters)
            if isempty(varargin)
                distance = dir*app.StepLengthumEditField.Value/1E6; %distance in microns converted to meters
                distance = Device.Units.positiontonative(distance); %meters to microsteps conversion
            else
                distance = varargin{1}/1E6; %distance in microns converted to meters
                distance = Device.Units.positiontonative(distance); %distance in meters converted to microsteps
            end
            
            Device.moverelative(distance)
            Device.waitforidle();
            app.UpdatePosition();
        end
        
        function results = RotateByStep(app,dir)
            StepSize = app.MoveByStepdegEditField.Value;
            NewTheta = StepSize*dir;
            NewTheta = NewTheta*(64000/360); %degrees to microstep;
            app.ThetaMotor.moverelative(NewTheta); 
            app.ThetaMotor.waitforidle();
            app.UpdateTheta();


        end
        
        function results = InitializeDevices(app,Devices)
            % Initializes individual devices from the Device handle, parsing addresses to functions on the stage setup.
            app.ReassignMotors;
            app.ThetaMotor = Devices(2); 
            app.XYMotorLamp.Color = [0,1,0];
            app.ReadCalibration();
            NewPos = double(app.ThetaMotor.getposition())*app.ThetaConv;
            app.CurrentPosdegEditField.Value = NewPos; 
            app.WaveplateLamp.Color = [0,1,0];
            
        end
        
        function results = Rotate2Pos(app,ThetaNew)
            ThetaNew = ThetaNew*(64000/360); %degrees to device units;
            app.ThetaMotor.moveabsolute(ThetaNew);
            app.ThetaMotor.waitforidle();
            app.UpdateTheta();
            
        end
        
        function results = ReassignMotors(app)
            Devices = app.PortHandle.Devices;
            %kind of just a placeholder until I can figure out unique identifiers
            XNum = app.XDeviceEditField.Value; YNum = app.YDeviceEditField.Value; ZNum = app.ZDeviceEditField.Value;
            app.XMotor = Devices(XNum); app.YMotor = Devices(YNum);
            app.ZMotor = Devices(ZNum);
            app.UpdatePosition();
        end
        
        function results = CalibrateWaveplateRoutine(app)
            %Setting up polling prompt/settings
            prompt = {'Input Power Measurement (mJ)'};
            dlgtitle = 'Calibration';
            k = 1;
            app.CalPower = [0,0];
            for theta = 0:5:90
                app.Rotate2Pos(theta);
                dlgtitle = sprintf('Calibration - %d degrees',theta);
                power = inputdlg(prompt,dlgtitle); power = str2double(power);
                power = (power/app.SamplerRatio)/1000; %Convert sampled power to total beam power in Joules.
                %poll the user for power measurement;
                Power(k,:) = [theta,power]; %Write new calibration to app
                k = k+1;
            end
            for i = 1:3
                theta = theta+i*90;
                Power(k,:) = [theta,power];
                k = k+1;
            end
            app.CalPower = Power;
            dlmwrite('Power_Rotation_Calibration.txt',app.CalPower,',');
        end
        
        function ReadCalibration(app)
            if exist('Power_Rotation_Calibration.txt','file')
                app.CalPower = dlmread('@Waveplate\Power_Rotation_Calibration.txt',',');
            else
                Decision = questdlg('No calibration found, would you like to make one now?',...
                    'No Calibration', ...
                    'Yes','No','No');
                switch Decision
                    case 'Yes'
                        app.CalibrateWaveplateRoutine();
                    case 'No'
                        app.CalPower = [0,0];
                end
            end
        end
        
        function EstPower = Power(app,theta)
            %Estimate Power based off of calibration curve
                if app.CalPower == [0,0] %case where not initialized
                    EstPower = 0;
                else
                    [val,idx] = min(abs(app.CalPower(:,1) - theta)); %find closest angle to a calibration point and its distance
                    %Check if we're close to a current calibration point, otherwise linearly interpolate.
                    if val < 1
                        EstPower = app.CalPower(idx,2);
                    elseif app.CalPower(idx,1) < theta
                        %nearest point is behind current angle
                        xi = (app.CalPower(idx,1):0.1:app.CalPower(idx+1,1))';
                        yi = interp1q([app.CalPower(idx,1),app.CalPower(idx+1,1)]',...
                        [app.CalPower(idx,2),app.CalPower(idx+1,2)]',xi);
                        [~,int_idx] = min(abs(yi-power));
                        EstPower = yi(int_idx);
                    elseif app.CalPower(idx,1) > theta
                        xi = (app.CalPower(idx-1,1):0.1:app.CalPower(idx,1))';
                        yi = interp1q([app.CalPower(idx-1,1),app.CalPower(idx,1)]',...
                        [app.CalPower(idx-1,2),app.CalPower(idx,2)]',xi);
                        [~,int_idx] = min(abs(yi-power));
                        EstPower = yi(int_idx);
                    end
            end
                    
                    
        end
        
        
        function results = UpdateTheta(app)
            NewTheta = app.ThetaMotor.getposition();
            app.CurrentPosdegEditField.Value = mod(double(NewTheta*(360/64000)),360); %Device Units to degrees
            drawnow;
        end
    end

    methods (Access = public)
    
        function results = XYHome(app)
            app.XMotor.home();
            app.YMotor.home();
            app.UpdatePosition();
        end
        
        function results = UpdatePosition(app)
            %assign coordinates to current position
            XPos = app.XMotor.getposition(); %collects current position in device step units
            XPos = double((app.XMotor.Units.nativetoposition(XPos))/1E-3); %convert from microstep to meters, meters to mm
            try
            app.XPosEditField.Value = XPos;
            drawnow;
            catch
            end
            YPos = app.YMotor.getposition();
            try
            app.YPosEditField.Value = double(app.YMotor.Units.nativetoposition(YPos)/1E-3); %device step to meters, meters to mm
            drawnow;
            catch
            end
            
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Change app designer text because it's pretty damn inflexible
            app.Arduino = arduino();
            app.RightButton.Text = char(8594);
            app.LeftButton.Text = char(8592);
            app.UpButton.Text = char(8593);
            app.DownButton.Text = char(8595);
            %Initializing code
            try
                app.PortHandle = ZaberPort(app.COM_Port); %initialize the zaber port object
                Devices = app.PortHandle.Devices;
                app.InitializeDevices(Devices);
            catch ME
                f = errordlg('Could not initialize devices');
                waitfor(f);
                app.UIFigureCloseRequest();
            end
            
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            app.MoveByStep(0);
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            app.MoveByStep(1);
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            app.MoveByStep(2);
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            app.MoveByStep(3);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try app.PortHandle.ClosePort();
                
            catch ME
                
            end
            delete(app)
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %+ button
            app.RotateByStep(1)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
             %- button
            app.RotateByStep(-1)
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            %+Z button
            app.MoveByStep(4);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            %-Z button
            app.MoveByStep(5);
        end

        % Value changed function: XDeviceEditField
        function XDeviceEditFieldValueChanged(app, event)
            app.ReassignMotors();
            
        end

        % Value changed function: YDeviceEditField
        function YDeviceEditFieldValueChanged(app, event)
            app.ReassignMotors();
            
        end

        % Value changed function: ZDeviceEditField
        function ZDeviceEditFieldValueChanged(app, event)
            app.ReassignMotors();
            
        end

        % Value changed function: MovetoAngleEditField
        function MovetoAngleEditFieldValueChanged(app, event)
            value = app.MovetoAngleEditField.Value;
            app.Rotate2Pos(value);
            
        end

        % Button pushed function: XYHomeButton
        function XYHomeButtonPushed(app, event)
            app.XYHome();
        end

        % Menu selected function: RecalibrateWaveplateMenu
        function RecalibrateWaveplateMenuSelected(app, event)
            app.CalibrateWaveplateRoutine();
        end

        % Value changed function: XPosEditField, YPosEditField
        function XPosEditFieldValueChanged(app, event)
            Xvalue = app.XPosEditField.Value/1000;
            Yvalue = app.YPosEditField.Value/1000; %specified coordinates in m
            Xvalue = app.XMotor.Units.positiontonative(Xvalue);
            Yvalue = app.YMotor.Units.positiontonative(Yvalue);
            app.XMotor.moveabsolute(Xvalue);
            app.XMotor.waitforidle();
            app.YMotor.moveabsolute(Yvalue);
            app.YMotor.waitforidle();
            
            
        end

        % Button pushed function: ThetaHomeButton
        function ThetaHomeButtonPushed(app, event)
            app.ThetaMotor.home();
        end

        % Value changed function: ShutterStatusKnob
        function ShutterStatusKnobValueChanged(app, event)
           value = app.ShutterStatusKnob.Value;
            if strcmp(value,'Closed')
                app.XYMotorLamp_2.Color=[1,1,1];
                writeDigitalPin(app.Arduino,'D11',0); % Turns off Arduino pin
                app.XYMotorLamp_2.Color=[0,1,0];
            elseif strcmp(value, 'Inhibit Mode')
                app.XYMotorLamp_2.Color=[1,1,1];
                writeDigitalPin(app.Arduino,'D11',0);
                app.XYMotorLamp_2.Color=[0,1,0];
            elseif strcmp(value,'Open')
                app.XYMotorLamp_2.Color=[1,1,1];
                writeDigitalPin(app.Arduino,'D11',1); % Turns on Arduino pin
                app.XYMotorLamp_2.Color=[0,1,0];
            end
        end

        % Value changed function: FireSinglePulseButton
        function FireSinglePulseButtonValueChanged(app, event)
             value = app.ShutterStatusKnob.Value;
             if strcmp(value,'Inhibit Mode')
                pause(2);
                writeDigitalPin(app.Arduino,'D11',1);
                pause(2);
                writeDigitalPin(app.Arduino,'D11',0);
            else
                app.XYMotorLamp_2.Color=[1,0,0];
                pause(1);
                app.XYMotorLamp_2.Color=[0,1,0];
             end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.Menu_2 = uimenu(app.FileMenu);
            app.Menu_2.Text = 'Menu';
            app.SettingsMenu_2 = uimenu(app.UIFigure);
            app.SettingsMenu_2.Text = 'Settings';
            app.RecalibrateWaveplateMenu = uimenu(app.SettingsMenu_2);
            app.RecalibrateWaveplateMenu.MenuSelectedFcn = createCallbackFcn(app, @RecalibrateWaveplateMenuSelected, true);
            app.RecalibrateWaveplateMenu.Text = 'Recalibrate Waveplate';
            app.StageAdjustmentPanel = uipanel(app.UIFigure);
            app.StageAdjustmentPanel.Title = 'Stage Adjustment';
            app.LeftButton = uibutton(app.StageAdjustmentPanel, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.StageAdjustmentPanel, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.DownButton = uibutton(app.StageAdjustmentPanel, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.UpButton = uibutton(app.StageAdjustmentPanel, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.StepLengthumEditFieldLabel = uilabel(app.StageAdjustmentPanel);
            app.StepLengthumEditFieldLabel.Text = 'Step Length (um)';
            app.StepLengthumEditField = uieditfield(app.StageAdjustmentPanel, 'numeric');
            app.StepLengthumEditField.Limits = [0 5000];
            app.StepLengthumEditField.ValueDisplayFormat = '%.0f';
            app.StepLengthumEditField.Value = 1000;
            app.Button_3 = uibutton(app.StageAdjustmentPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '+';
            app.Button_4 = uibutton(app.StageAdjustmentPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '-';
            app.EditField = uieditfield(app.StageAdjustmentPanel, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'Focus Toggle';
            app.XPosEditFieldLabel = uilabel(app.StageAdjustmentPanel);
            app.XPosEditFieldLabel.Text = 'X Pos';
            app.XPosEditField = uieditfield(app.StageAdjustmentPanel, 'numeric');
            app.XPosEditField.Limits = [0 75];
            app.XPosEditField.ValueDisplayFormat = '%.3f';
            app.XPosEditField.ValueChangedFcn = createCallbackFcn(app, @XPosEditFieldValueChanged, true);
            app.YPosEditFieldLabel = uilabel(app.StageAdjustmentPanel);
            app.YPosEditFieldLabel.Text = 'Y Pos';
            app.YPosEditField = uieditfield(app.StageAdjustmentPanel, 'numeric');
            app.YPosEditField.Limits = [0 75];
            app.YPosEditField.ValueDisplayFormat = '%.3f';
            app.YPosEditField.ValueChangedFcn = createCallbackFcn(app, @XPosEditFieldValueChanged, true);
            app.PowerAdjustmentPanel = uipanel(app.UIFigure);
            app.PowerAdjustmentPanel.Title = 'Power Adjustment';
            app.EstPowerGaugeLabel = uilabel(app.PowerAdjustmentPanel);
            app.EstPowerGaugeLabel.Text = 'Est. Power';
            app.EstPowerGauge = uigauge(app.PowerAdjustmentPanel, 'linear');
            app.EstPowerGauge.Limits = [0 10];
            app.JEditFieldLabel = uilabel(app.PowerAdjustmentPanel);
            app.JEditFieldLabel.Text = 'J';
            app.JEditField = uieditfield(app.PowerAdjustmentPanel, 'numeric');
            app.JEditField.Editable = 'off';
            app.MovetoPowerJEditFieldLabel = uilabel(app.PowerAdjustmentPanel);
            app.MovetoPowerJEditFieldLabel.Text = 'Move to Power (J)';
            app.MovetoPowerJEditField = uieditfield(app.PowerAdjustmentPanel, 'numeric');
            app.MoveByStepdegEditFieldLabel = uilabel(app.PowerAdjustmentPanel);
            app.MoveByStepdegEditFieldLabel.Text = 'Move By Step (deg)';
            app.MoveByStepdegEditField = uieditfield(app.PowerAdjustmentPanel, 'numeric');
            app.MoveByStepdegEditField.Value = 1;
            app.Button = uibutton(app.PowerAdjustmentPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.PowerAdjustmentPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '-';
            app.MovetoAngleEditFieldLabel = uilabel(app.PowerAdjustmentPanel);
            app.MovetoAngleEditFieldLabel.Text = 'Move to Angle';
            app.MovetoAngleEditField = uieditfield(app.PowerAdjustmentPanel, 'numeric');
            app.MovetoAngleEditField.ValueChangedFcn = createCallbackFcn(app, @MovetoAngleEditFieldValueChanged, true);
            app.MovetoAngleEditField.Value = 50;
            app.CurrentPosdegEditFieldLabel = uilabel(app.PowerAdjustmentPanel);
            app.CurrentPosdegEditFieldLabel.Text = 'Current Pos (deg)';
            app.CurrentPosdegEditField = uieditfield(app.PowerAdjustmentPanel, 'numeric');
            app.CurrentPosdegEditField.Editable = 'off';
            app.CurrentPosdegEditField.Value = 50;
            app.SettingsDebuggingPanel = uipanel(app.UIFigure);
            app.SettingsDebuggingPanel.Title = 'Settings-Debugging';
            app.XDeviceEditFieldLabel = uilabel(app.SettingsDebuggingPanel);
            app.XDeviceEditFieldLabel.Text = 'X Device';
            app.XDeviceEditField = uieditfield(app.SettingsDebuggingPanel, 'numeric');
            app.XDeviceEditField.ValueChangedFcn = createCallbackFcn(app, @XDeviceEditFieldValueChanged, true);
            app.XDeviceEditField.Value = 3;
            app.YDeviceEditFieldLabel = uilabel(app.SettingsDebuggingPanel);
            app.YDeviceEditFieldLabel.Text = 'Y Device';
            app.YDeviceEditField = uieditfield(app.SettingsDebuggingPanel, 'numeric');
            app.YDeviceEditField.ValueChangedFcn = createCallbackFcn(app, @YDeviceEditFieldValueChanged, true);
            app.YDeviceEditField.Value = 4;
            app.ZDeviceEditFieldLabel = uilabel(app.SettingsDebuggingPanel);
            app.ZDeviceEditFieldLabel.Text = 'Z Device';
            app.ZDeviceEditField = uieditfield(app.SettingsDebuggingPanel, 'numeric');
            app.ZDeviceEditField.ValueChangedFcn = createCallbackFcn(app, @ZDeviceEditFieldValueChanged, true);
            app.ZDeviceEditField.Value = 5;
            app.XYMotorLampLabel = uilabel(app.SettingsDebuggingPanel);
            app.XYMotorLampLabel.Text = 'XY Motor';
            app.XYMotorLamp = uilamp(app.SettingsDebuggingPanel);
            app.WaveplateLampLabel = uilabel(app.SettingsDebuggingPanel);
            app.WaveplateLampLabel.Text = 'Waveplate';
            app.WaveplateLamp = uilamp(app.SettingsDebuggingPanel);
            app.XYHomeButton = uibutton(app.SettingsDebuggingPanel, 'push');
            app.XYHomeButton.ButtonPushedFcn = createCallbackFcn(app, @XYHomeButtonPushed, true);
            app.XYHomeButton.Text = 'XY Home';
            app.ThetaHomeButton = uibutton(app.SettingsDebuggingPanel, 'push');
            app.ThetaHomeButton.ButtonPushedFcn = createCallbackFcn(app, @ThetaHomeButtonPushed, true);
            app.ThetaHomeButton.Text = 'Theta Home';
            app.ShutterControlPanel = uipanel(app.UIFigure);
            app.ShutterControlPanel.Title = 'Shutter Control';
            app.ShutterStatusKnobLabel = uilabel(app.ShutterControlPanel);
            app.ShutterStatusKnobLabel.Text = 'Shutter Status';
            app.ShutterStatusKnob = uiknob(app.ShutterControlPanel, 'discrete');
            app.ShutterStatusKnob.Items = {'Closed', 'Inhibit Mode', 'Open'};
            app.ShutterStatusKnob.ValueChangedFcn = createCallbackFcn(app, @ShutterStatusKnobValueChanged, true);
            app.ShutterStatusKnob.Value = 'Closed';
            app.XYMotorLamp_2Label = uilabel(app.ShutterControlPanel);
            app.XYMotorLamp_2Label.Text = 'XY Motor';
            app.XYMotorLamp_2 = uilamp(app.ShutterControlPanel);
            app.FireSinglePulseButton = uibutton(app.ShutterControlPanel, 'state');
            app.FireSinglePulseButton.ValueChangedFcn = createCallbackFcn(app, @FireSinglePulseButtonValueChanged, true);
            app.FireSinglePulseButton.Text = 'Fire Single Pulse';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ControlUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end